classdef TongueTrackerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OpenFile_M                 matlab.ui.control.Button
        Play_btn                   matlab.ui.control.Button
        Track_btn                  matlab.ui.control.Button
        FrameCountLabel            matlab.ui.control.Label
        Pause_btn                  matlab.ui.control.Button
        Stop_btn                   matlab.ui.control.Button
        OpenFile_R                 matlab.ui.control.Button
        OpenFile_L                 matlab.ui.control.Button
        Time_slider                matlab.ui.control.Slider
        FrameFront_btn             matlab.ui.control.Button
        FrameBack_btn              matlab.ui.control.Button
        FrameCount_txt             matlab.ui.control.Label
        TrackEyes_btn              matlab.ui.control.Button
        TrackNose_btn              matlab.ui.control.Button
        TrackLabel                 matlab.ui.control.Label
        Plot3D_btn                 matlab.ui.control.Button
        TongueTrackingSystemLabel  matlab.ui.control.Label
        Video_M                    matlab.ui.control.UIAxes
        Video_L                    matlab.ui.control.UIAxes
        Video_R                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FileNameM = "" % Video File address
        FileNameL = "" % Video File address
        FileNameR = "" % Video File address
    end
    
    methods (Access = private)
        function points = createNewTrackingBox(app, frame)
            % Open figure to define region
            f1 = figure; imshow(frame);
            objectRegion =round(getPosition(imrect));
            
            objectImage_M = insertShape(frame,'Rectangle',objectRegion,'Color','red');
            close(f1); f2 = figure;
            imshow(objectImage_M);
            title('Red box shows object region');
            
            points = detectMinEigenFeatures(im2gray(frame),'ROI',objectRegion);
            
        end
        
        function points = inicializeTrackingPoints(app, frame, points)
            
            tracker = vision.PointTracker('MaxBidirectionalError',1);
            points_L = points.Location;
            
            % Initialize the tracker with the initial point locations and the initial
            initialize(tracker,points_L,frame);
            
            points = points_L;
            
        end
        
        function frameT = TrackPoints(app,oldPoints, points, rectangle, frame)
            %             [points_M, isFound] = step(tracker_M, frame);
            %             visiblePoints = points_M(isFound, :);
            %             oldInliers = oldPoints(isFound, :);
            %
            %             % Estimate the geometric transformation between the old points
            %             % and the new points and eliminate outliers
            %             [xform, inlierIdx] = estimateGeometricTransform2D(...
            %                 oldInliers, visiblePoints, 'similarity', 'MaxDistance', 4);
            %             oldInliers    = oldInliers(inlierIdx, :);
            %             visiblePoints = visiblePoints(inlierIdx, :);
            %
            %             % Display tracked points
            %             frame = insertMarker(frame, visiblePoints, '+', ...
            %                 'Color', 'white');
            frameT = frame;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenFile_M
        function OpenFile_MPushed(app, event)
            [ video_file_name,video_file_path ] = uigetfile({'*.avi'},'Pick a video file');      %;*.png;*.yuv;*.bmp;*.tif'},'Pick a file');
            if(video_file_path == 0)
                return;
            end
            input_video_file = [video_file_path,video_file_name];
            app.FileNameM = input_video_file;
            videoObject =  VideoReader(input_video_file);%'subject1/proefpersoon 1.1_M.avi');
            frame = readFrame(videoObject);
            
            %Place the first frame
            imagesc(app.Video_M,frame);
            
        end

        % Button pushed function: Play_btn
        function Play_btnPushed(app, event)
            global numFrames pauseS stop iter  
            
            pauseS=true;
            stop = true;
            videoR_exist = false;
            videoL_exist = false;
            
            
            %Check if videos address are uploaded
            if(app.FileNameM == "")
                return;
            end
            
            if(app.FileNameL ~= "")
                v_l = VideoReader(app.FileNameL);
                videoL_exist = true;
            end
            if(app.FileNameR ~= "")
                v_r = VideoReader(app.FileNameR);
                videoR_exist = true;
            end
            
            % Count the number of frames
            v_m = VideoReader(app.FileNameM);
            numFrames = v_m.NumberOfFrames;
            
            iter=1;
            
            while hasFrame(v_m)
                app.FrameCountLabel.Text = string(iter);
                frame_m = read(v_m,iter);
                imagesc(app.Video_M,frame_m);
                
                if(videoR_exist)
                    frame_r = read(v_r, iter);
                    imagesc(app.Video_R,frame_r);
                end
                
                if(videoL_exist)
                    imagesc(app.Video_L,frame_l);
                    frame_l = read(v_l, iter);
                end
                
                %Here for pause and resume
                if pauseS == false
                    pause(0.001);
                    while(1)
                        if pauseS == true
                            break;
                        end
                    end
                end
                
                
                iter = iter+1;
                % If frame is finished, break
                if iter>numFrames
                    break;
                end
            end
            
        end

        % Button pushed function: Pause_btn
        function Pause_btnPushed(app, event)
            global pauseS
            if pauseS==true
                pauseS=false;
            else
                pauseS=true;
            end
        end

        % Button pushed function: OpenFile_L
        function OpenFile_LButtonPushed(app, event)
            [ video_file_name,video_file_path ] = uigetfile({'*.avi'},'Pick a video file');      %;*.png;*.yuv;*.bmp;*.tif'},'Pick a file');
            if(video_file_path == 0)
                return;
            end
            input_video_file = [video_file_path,video_file_name];
            app.FileNameL = input_video_file;
            videoObject =  VideoReader(input_video_file);%'subject1/proefpersoon 1.1_M.avi');
            frame = readFrame(videoObject);
            
            %Place the first frame
            imagesc(app.Video_L,frame);
        end

        % Button pushed function: OpenFile_R
        function OpenFile_RButtonPushed(app, event)
            [ video_file_name,video_file_path ] = uigetfile({'*.avi'},'Pick a video file');      %;*.png;*.yuv;*.bmp;*.tif'},'Pick a file');
            if(video_file_path == 0)
                return;
            end
            input_video_file = [video_file_path,video_file_name];
            app.FileNameR = input_video_file;
            videoObject =  VideoReader(input_video_file);%'subject1/proefpersoon 1.1_M.avi');
            frame = readFrame(videoObject);
            
            %Place the first frame
            imagesc(app.Video_R,frame);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenFile_M = uibutton(app.UIFigure, 'push');
            app.OpenFile_M.ButtonPushedFcn = createCallbackFcn(app, @OpenFile_MPushed, true);
            app.OpenFile_M.Icon = 'folder.png';
            app.OpenFile_M.Text = '';
            app.Play_btn = uibutton(app.UIFigure, 'push');
            app.Play_btn.ButtonPushedFcn = createCallbackFcn(app, @Play_btnPushed, true);
            app.Play_btn.Icon = 'play-button.png';
            app.Play_btn.Text = 'Play';
            app.Track_btn = uibutton(app.UIFigure, 'push');
            app.Track_btn.Icon = 'track.png';
            app.Track_btn.Text = '';
            app.FrameCountLabel = uilabel(app.UIFigure);
            app.FrameCountLabel.Text = 'FrameCount';
            app.Pause_btn = uibutton(app.UIFigure, 'push');
            app.Pause_btn.ButtonPushedFcn = createCallbackFcn(app, @Pause_btnPushed, true);
            app.Pause_btn.Icon = 'pause.png';
            app.Pause_btn.Text = '';
            app.Stop_btn = uibutton(app.UIFigure, 'push');
            app.Stop_btn.Icon = 'stop.png';
            app.Stop_btn.Text = '';
            app.OpenFile_R = uibutton(app.UIFigure, 'push');
            app.OpenFile_R.ButtonPushedFcn = createCallbackFcn(app, @OpenFile_RButtonPushed, true);
            app.OpenFile_R.Icon = 'folder.png';
            app.OpenFile_R.Text = '';
            app.OpenFile_L = uibutton(app.UIFigure, 'push');
            app.OpenFile_L.ButtonPushedFcn = createCallbackFcn(app, @OpenFile_LButtonPushed, true);
            app.OpenFile_L.Icon = 'folder.png';
            app.OpenFile_L.Text = '';
            app.Time_slider = uislider(app.UIFigure);
            app.FrameFront_btn = uibutton(app.UIFigure, 'push');
            app.FrameFront_btn.Icon = 'next.png';
            app.FrameFront_btn.Text = '';
            app.FrameBack_btn = uibutton(app.UIFigure, 'push');
            app.FrameBack_btn.Icon = 'previous.png';
            app.FrameBack_btn.Text = '';
            app.FrameCount_txt = uilabel(app.UIFigure);
            app.FrameCount_txt.Text = '0';
            app.TrackEyes_btn = uibutton(app.UIFigure, 'push');
            app.TrackEyes_btn.Icon = 'eye.png';
            app.TrackEyes_btn.Text = '';
            app.TrackNose_btn = uibutton(app.UIFigure, 'push');
            app.TrackNose_btn.Icon = 'nose.png';
            app.TrackNose_btn.Text = '';
            app.TrackLabel = uilabel(app.UIFigure);
            app.TrackLabel.Text = 'Track';
            app.Plot3D_btn = uibutton(app.UIFigure, 'push');
            app.Plot3D_btn.Icon = '3d-model.png';
            app.Plot3D_btn.Text = '';
            app.TongueTrackingSystemLabel = uilabel(app.UIFigure);
            app.TongueTrackingSystemLabel.Text = 'Tongue Tracking System';
            app.Video_M = uiaxes(app.UIFigure);
            title(app.Video_M, 'Middle')
            app.Video_M.XTick = [];
            app.Video_M.XTickLabel = '';
            app.Video_M.YTick = [];
            app.Video_M.YTickLabel = '';
            app.Video_L = uiaxes(app.UIFigure);
            title(app.Video_L, 'Left')
            app.Video_L.XTick = [];
            app.Video_L.XTickLabel = '';
            app.Video_L.YTick = [];
            app.Video_L.YTickLabel = '';
            app.Video_R = uiaxes(app.UIFigure);
            title(app.Video_R, 'Right')
            app.Video_R.XTick = [];
            app.Video_R.XTickLabel = '';
            app.Video_R.YTick = [];
            app.Video_R.YTickLabel = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TongueTrackerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end