classdef TongueTrackerApp2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        OpenFile_M                 matlab.ui.control.Button
        Play_btn                   matlab.ui.control.Button
        Pause_btn                  matlab.ui.control.Button
        TongueTrackingSystemLabel  matlab.ui.control.Label
        AddTrackingPoint           matlab.ui.control.Button
        Plot3D_btn                 matlab.ui.control.Button
        TrackingPointLabel         matlab.ui.control.Label
        FrameLabel                 matlab.ui.control.Label
        FrameCount_txt             matlab.ui.control.Label
    end

    
    properties (Access = private)
        Pause = false % Pause Video
        TrackingSomething = false % Description
        AddTrackingP = false % Description
        
        FrameCount = 0 % Description
    end
    
    methods (Access = private)
        
        function Initializing(app)
            global filenames HistoryOfPoints facePoints_M
            facePoints_M = [];
            HistoryOfPoints = [];
            % Load the pairs of extrinsic parameter matrices.
            load("stereoParamsLM.mat"); load("stereoParamsRM.mat");
            
            videoReader_M = VideoReader(filenames(1,:));
            videoPlayer_M = vision.VideoPlayer();
            objectFrame_M = read(videoReader_M, 350);
            
            videoReader_R = VideoReader(filenames(2,:));
            videoPlayer_R = vision.VideoPlayer();
            objectFrame_R = read(videoReader_R, 350);
            
            videoReader_L = VideoReader(filenames(3,:));
            videoPlayer_L = vision.VideoPlayer();
            objectFrame_L = read(videoReader_L, 350);
            
            %imagesc(app.Video_M, objectFrame_M);
            %% Get the facial landmarks to create a coordinate system.
            
            figure(1); imshow(objectFrame_M); title('Select Points for Left Eye');
            [xi, yi] = getpts();            % Select points for the outer edge of left eye.
            facePoints_M = [xi yi];
            loc = repmat("Left Eye", size(xi)); % Vector to track which region the points correspond to.
            
            figure(2); imshow(objectFrame_M); title('Select Points for Right Eye');
            [xi, yi] = getpts();            % Select points for the outer edge of right eye.
            facePoints_M = [facePoints_M; xi yi];   % Append the new points.
            loc = [loc; repmat("Right Eye", size(xi))];   % Append the new region.
            
            figure(3); imshow(objectFrame_M); title('Select Points for Nose Tip');
            [xi, yi] = getpts();            % Select points for the outer edge of nose tip.
            facePoints_M = [facePoints_M; xi yi];   % Append the new points.
            loc = [loc; repmat("Nose", size(xi))];        % Append the new region.
            
            % Create the point tracker for each camera (possible to track different points in L and R)
            faceTracker_L = vision.PointTracker('MaxBidirectionalError', 1, 'NumPyramidLevels', 5);
            faceTracker_R = vision.PointTracker('MaxBidirectionalError', 1, 'NumPyramidLevels', 5);
            faceTracker_M = vision.PointTracker('MaxBidirectionalError', 1, ...
                'NumPyramidLevels', 2); % Less likely to change position =>
            % lower pyramid levels to save some computation.
            close(1); close(2); close(3);
            
            % Initialize the point trackers
            initialize(faceTracker_L, facePoints_M, objectFrame_M);
            initialize(faceTracker_R, facePoints_M, objectFrame_M);
            initialize(faceTracker_M, facePoints_M, objectFrame_M);
            
            % Find the points in the L and R camera frames.
            [facePoints_L, validIdx_L] = step(faceTracker_L, objectFrame_L);
            [facePoints_R, validIdx_R] = step(faceTracker_R, objectFrame_R);
            
            % Extract the valid matches.
            facePoints_M = facePoints_M(validIdx_L, :);
            facePoints_L = facePoints_L(validIdx_L, :);
            % facePoints_R = facePoints_R(validIdx_R & validIdx_L, :);
            save('PointsFaceL.mat', "facePoints_L");
            save('PointsFaceM.mat', "facePoints_M");
            save('PointsFaceR.mat', "facePoints_R");
            
            % Get the point indexes for Left Eye, Right Eye, etc. for the Left and Right cameras.
            le_id = (loc(validIdx_L)=="Left Eye");
            re_id = (loc(validIdx_L)=="Right Eye");
            n_id  = (loc(validIdx_L)=="Nose");
            
            ids = [le_id; re_id; n_id];
            newFacePts = translate_coords(facePoints_M, facePoints_L, ...
                stereoParamsLM, facePoints_M(le_id,:), facePoints_L(le_id,:),...
                facePoints_M(re_id,:), facePoints_L(re_id,:), [0 0], [0 0], [0 0 0], false);
            
            % Save original nose position to compare to subsequent facial coordinate systems.
            if size(newFacePts(n_id,:),1) > 1
                orig_nose = median(newFacePts(n_id,:));
            else, orig_nose = newFacePts(n_id,:);
            end
            
            % Trackers for tracking from middle to left & right cameras. Allow higher
            % pyramid levels since position can be shifted more.
            tracker_R = vision.PointTracker('MaxBidirectionalError',2,'NumPyramidLevels',6);
            tracker_L = vision.PointTracker('MaxBidirectionalError',2,'NumPyramidLevels',6);
            
            save('FileNames.mat',"filenames" );
            save('FaceSaved.mat','newFacePts');
            save('FaceId.mat', 'ids');
            
        end
        
        function Plot3D(app)
            global  filenames
            frameCount = 1;
            load("stereoParamsLM.mat"); load("stereoParamsRM.mat");
            saved_pts_L=[]; saved_pts_R=[];             % Arrays to hold the tongue points.
            
            if ~exist('PointsSaved.mat', 'file')
                message = sprintf('File does not exist');
                uiwait(warndlg(message));
            else
                HistoryPointsFile = matfile('PointsSaved.mat');
                HistoryOfPoints = HistoryPointsFile.HistoryOfPoints;
            end
            
            if ~exist('FaceSaved.mat', 'file')
                message = sprintf('File does not exist');
                uiwait(warndlg(message));
            else
                newFacePtsFile = matfile('FaceSaved.mat');
                newFacePts = newFacePtsFile.newFacePts;
                ids = matfile('FaceId.mat').ids;
                le_id = ids(1);
                re_id = ids(2);
                n_id  = ids(3);
                newFacePtsFile = matfile('FaceSaved.mat');
                
                facePoints_R = matfile('PointsFaceR.mat').facePoints_R;
                facePoints_L = matfile('PointsFaceL.mat').facePoints_L;
                facePoints_M = matfile('PointsFaceM.mat').facePoints_M;
                
                
                % Save original nose position to compare to subsequent facial coordinate systems.
                if size(newFacePts(n_id,:),1) > 1
                    orig_nose = median(newFacePts(n_id,:));
                else, orig_nose = newFacePts(n_id,:);
                end
            end
            newFileNames = matfile('FileNames.mat');
            filenames = newFileNames.filenames;


            % Trackers for tracking from middle to left & right cameras. Allow higher
            % pyramid levels since position can be shifted more.
            tracker_R = vision.PointTracker('MaxBidirectionalError',2,'NumPyramidLevels',6);
            tracker_L = vision.PointTracker('MaxBidirectionalError',2,'NumPyramidLevels',6);
            
            % Create the point tracker for each camera (possible to track different points in L and R)
            faceTracker_L = vision.PointTracker('MaxBidirectionalError', 1, 'NumPyramidLevels', 5);
            faceTracker_R = vision.PointTracker('MaxBidirectionalError', 1, 'NumPyramidLevels', 5);
            faceTracker_M = vision.PointTracker('MaxBidirectionalError', 1, ...
                'NumPyramidLevels', 2); % Less likely to change position =>
            % lower pyramid levels to save some computation.


            videoReader_M = VideoReader(filenames(1,:));
            videoReader_R = VideoReader(filenames(2,:));
            videoReader_L = VideoReader(filenames(3,:));
            
            objectFrame_M = read(videoReader_M, 1);
            objectFrame_R = read(videoReader_R, 1);
            objectFrame_L = read(videoReader_L, 1);
            
            % Initialize the point trackers
            initialize(faceTracker_L, facePoints_M, objectFrame_M);
            initialize(faceTracker_R, facePoints_M, objectFrame_M);
            initialize(faceTracker_M, facePoints_M, objectFrame_M);
            figure(); hold on;   % Create a figure to hold the world coordinates plots.
            title('Y vs. X world coordinates of tracked tongue points');
            xlabel('X'); ylabel('Y');
            % for i = 1:frameCount
            while hasFrame(videoReader_M)
                app.FrameCount
                app.FrameCount_txt.Text = string(app.FrameCount);
                
                visiblePoints = [HistoryOfPoints(:,(app.FrameCount*2)-1), HistoryOfPoints(:,app.FrameCount*2)];
                if(visiblePoints(1,1) ~= 0 )
                    nArray = find(isnan(visiblePoints));
                    if ~isempty(nArray(1))
                        vx =visiblePoints(:,1)
                        vy = visiblePoints(:,2);
                        n = nArray(1);
                        vx = vx(1: n-1);
                        vy = vy(1: n-1);
                        visiblePoints = [vx, vy];
                        %visibleList((visibleList()):(visibleList()));
                    end
                    
                    % Track the points from the middle camera to the right camera.
                    frame_M = read(videoReader_M, app.FrameCount);
                    frame_R = read(videoReader_R, app.FrameCount);
                    
                    % Track the points from the middle camera to the right camera.
                    release(tracker_R);
                    initialize(tracker_R, visiblePoints, frame_M);
                    [points_R,validIdx_tongue_R] = tracker_R(frame_R);
                    out_R = insertMarker(frame_R,points_R(validIdx_tongue_R, :),'+');
                    %step(v2, out_R);
                    
                    % Track the points from the middle camera to the left camera.
                    release(tracker_L);
                    initialize(tracker_L, visiblePoints, frame_M);
                    frame_L = readFrame(videoReader_L);
                    [points_L,validIdx_tongue_L] = tracker_L(frame_L);
                    out_L = insertMarker(frame_L,points_L(validIdx_tongue_L, :),'+');
                    %step(v3, out_L);
                    
                    % Track the facial landmarks.
                    [facePts_L, validIdx_L] = step(faceTracker_L, frame_L);
                    [facePts_R, validIdx_R] = step(faceTracker_R, frame_R);
                    [facePts_M, validIdx_M] = step(faceTracker_M, frame_M);
                    % Get the point indexes for Left Eye, Right Eye, etc. for the Left and Middle cameras.
                    %  le_id = (loc(validIdx_L & validIdx_M) == "Left Eye");
                    %  re_id = (loc(validIdx_L & validIdx_M) == "Right Eye");
                    %  n_id  = (loc(validIdx_L & validIdx_M) == "Nose");
                    
                    % Check that there is at least one point for the tongue and facial landmarks.
                    if min([max(le_id), max(re_id), max(n_id), max(validIdx_tongue_L)]) > 0
                        % Get the coordinates relative to the facial coordinate system.
                        tongue_pts_L = translate_coords( visiblePoints(validIdx_tongue_L,:), ...
                            points_L(validIdx_tongue_L, :), stereoParamsLM, facePts_M(le_id,:),...
                            facePts_L(le_id,:), facePts_M(re_id,:),facePts_L(re_id,:), ...
                            facePts_M(n_id,:), facePts_L(n_id,:), orig_nose, true);
                        % Plot the X, Y coordinates of the points.
                        scatter(tongue_pts_L(:,1), tongue_pts_L(:,2),16,'blue','filled')
                        saved_pts_L = [saved_pts_L; tongue_pts_L]; % Keep a record of past points.
                    end
                    
                    % Same procedure as above but for the middle and right cameras.
                    % Get the point indexes for Left Eye, Right Eye, etc. for the Left and Right cameras.
                    %                     le_id = (loc(validIdx_M & validIdx_R) == "Left Eye");
                    %                     re_id = (loc(validIdx_M & validIdx_R) == "Right Eye");
                    %                     n_id  = (loc(validIdx_M & validIdx_R) == "Nose");
                    
                    % Check that there is at least one point for the tongue and facial landmarks.
                    if min([max(le_id), max(re_id), max(n_id), max(validIdx_tongue_R)]) > 0
                        % Get the coordinates relative to the facial coordinate system.
                        tongue_pts_R = translate_coords( visiblePoints(validIdx_tongue_R,:), ...
                            points_R(validIdx_tongue_R, :), stereoParamsRM, facePts_M(le_id,:),...
                            facePts_R(le_id,:), facePts_M(re_id,:),facePts_R(re_id,:), ...
                            facePts_M(n_id,:), facePts_R(n_id,:), orig_nose, true);
                        % Plot the X, Y coordinates of the points.
                        scatter(tongue_pts_R(:,1), tongue_pts_R(:,2),16,'red','filled')
                        saved_pts_R = [saved_pts_R; tongue_pts_R]; % Keep a record of past points.
                    end
                end
            end
            app.FrameCount = 0;
        end
        
        function TrackOnVideo(app, filenames)
            global  HistoryOfPoints;
            %app.FrameCount = 0;
            if ~exist('FileNames.mat', 'file')
                message = sprintf('File does not exist');
                uiwait(warndlg(message));
            else
                filenames = matfile('FileNames.mat').filenames;
            end
            videoReader_M = VideoReader(filenames(1,:));
            objectFrame_M = read(videoReader_M, 1);
            
            videoReader_R = VideoReader(filenames(2,:));
            objectFrame_R = read(videoReader_R, 1);
            
            videoReader_L = VideoReader(filenames(3,:));
            objectFrame_L = read(videoReader_L, 1);
            videoPlayer  = vision.VideoPlayer('Position',...
                [500 1 650 650]);
            
            while hasFrame(videoReader_M)
                frame_M = readFrame(videoReader_M);
                
                
                pX = zeros(900,1);%%empty array of 0 that will be added at the end if there is any tracking point
                pY = zeros(900,1);%%empty array of 0 that will be added at the end if there is any tracking point
                
                if(app.Pause)
                    if(app.AddTrackingP)
                        %% Get the initial points to track in the middle camera.
                        app.AddTrackingP = false;
                        tonguePoints_M= "";
                        figure(4); imshow(frame_M); title('Draw rectangle around tongue tip');
                        objectRegion_M=round(getPosition(imrect));   % User selects the rectangle of interest.
                        close(4);
                        tonguePoints_M = detectMinEigenFeatures(im2gray(frame_M),'ROI',objectRegion_M,...
                            'MinQuality',0.001); % Reduce Quality to get more pts.
                        
                        % Initialise point tracker for the middle camera - position should not
                        % change too much from frame to frame => PyramidLevels to reduce computation time.
                        tracker_M = vision.PointTracker('MaxBidirectionalError',1, 'NumPyramidLevels',3);
                        tonguePoints_M = tonguePoints_M.Location;
                        
                        % Initialize the tracker with the initial point locations.
                        initialize(tracker_M, tonguePoints_M, frame_M);
                        
                        if(tonguePoints_M > 8)
                            oldPoints = tonguePoints_M;
                            
                            [points_M, isFound] = step(tracker_M, frame_M);
                            visiblePoints = points_M(isFound, :);
                            
                            oldCentre = median(visiblePoints);
                            
                            
                            app.TrackingSomething = true;
                        end
                        app.Pause = false;
                    end
                    while(1)
                        pause(0.0005);
                        if app.Pause == false
                            break;
                        end
                    end
                else
                    app.FrameCount = app.FrameCount + 1;
                    app.FrameCount_txt.Text = string(app.FrameCount);
                end
                
                % Track the points. Note that some points may be lost.
                
                if(app.TrackingSomething)
                    [points_M, isFound] = step(tracker_M, frame_M);
                    visiblePoints = points_M(isFound, :);
                    oldInliers = oldPoints(isFound, :);
                    % Recapture interest points if they drop below 10.
                    if size(visiblePoints, 1) <= 10
                        % If num of points too low, manually re-select rectangle.
                        if size(visiblePoints, 1) <= 1
                            figure(5); imshow(frame_M); title('Draw rectangle around tongue tip');
                            objectRegion_M=round(getPosition(imrect));   % User selects the rectangle of interest.
                            close(5);
                        else
                            % Get the current motion and assume it will partly continue.
                            shift = median(visiblePoints) - oldCentre;
                            newCentre = median(visiblePoints) + shift/2;
                            % The new rectangle will have the same size as the old one.
                            objectRegion_M(1:2) = newCentre - objectRegion_M(3:4)/2;
                        end
                        %                         m1 =visiblePoints(:,1);
                        %                         m2 =visiblePoints(:,2);
                        %
                        %                         mediaX = round(median(m1));
                        %                         mediaY = round(median(m2));
                        %                         objectRegion_M(1) = mediaX - objectRegion_M(3:4);
                        %                         objectRegion_M(2) = mediaY - objectRegion_M(3:4)/2;
                        points_M = detectMinEigenFeatures(im2gray(frame_M),'ROI',objectRegion_M,...
                            'MinQuality',0.001);
                        
                        setPoints(tracker_M, points_M.Location);
                        visiblePoints = points_M.Location;
                    else
                        % Estimate the geometric transformation between the old points
                        % and the new points and eliminate outliers
                        [xform, inlierIdx] = estimateGeometricTransform2D(...
                            oldInliers, visiblePoints, 'similarity', 'MaxDistance', 4);
                        oldInliers    = oldInliers(inlierIdx, :);
                        visiblePoints = visiblePoints(inlierIdx, :);
                    end
                    
                    % Display tracked points
                    frame_M = insertMarker(frame_M, visiblePoints, '+', 'Color', 'white');
                    
                    % Reset the points
                    oldPoints = visiblePoints;
                    setPoints(tracker_M, oldPoints);
                    oldCentre = median(visiblePoints);
                    
                    %Adding vector point to one single row
                    visibleList = visiblePoints;
                    
                    %%Adding Points to File
                    rP= size(HistoryOfPoints,1);
                    rV= size(visibleList,1);
                    if rP >= rV
                        
                        pX = visibleList(:,1);
                        pY = visibleList(:,2);
                        pX(rV : rP) = nan;
                        pY(rV : rP) = nan;
                    else
                        pX = visibleList(1:rP);
                        pY = visibleList(1:rP);
                    end
                    %HistoryOfPoints = [HistoryOfPoints; z];
                    
                    
                    % Display the annotated video frame using the video player object
                    step(videoPlayer, frame_M);
                    pause(0.05);
                    
                end
                HistoryOfPoints = [HistoryOfPoints, pX];
                HistoryOfPoints = [HistoryOfPoints, pY];
                
                step(videoPlayer, frame_M);
                pause(0.01);
            end
            save('PointsSaved.mat','HistoryOfPoints');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.FrameCount = 0;
        end

        % Button pushed function: OpenFile_M
        function OpenFile_MPushed(app, event)
            global filenames
            filenames = "";
            [ video_file_name_L,video_file_path_L ] = uigetfile({'*.avi'},'Pick a video file - Left Side');      %;*.png;*.yuv;*.bmp;*.tif'},'Pick a file');
            [ video_file_name_M,video_file_path_M ] = uigetfile({'*.avi'},'Pick a video file - Middle Side');      %;*.png;*.yuv;*.bmp;*.tif'},'Pick a file');
            [ video_file_name_R,video_file_path_R ] = uigetfile({'*.avi'},'Pick a video file - Right Side');      %;*.png;*.yuv;*.bmp;*.tif'},'Pick a file');
            if(video_file_path_M == 0)
                return;
            end
            input_video_file_L = [video_file_path_L,video_file_name_L];
            input_video_file_M = [video_file_path_M,video_file_name_M];
            input_video_file_R = [video_file_path_R,video_file_name_R];
            
            filenames = [input_video_file_M; input_video_file_R; input_video_file_L];
            %videoObject =  VideoReader(input_video_file);%'subject1/proefpersoon 1.1_M.avi');
            %frame = readFrame(videoObject);
            
            %Place the first frame
            %imagesc(app.Video_M,frame);
            app.Initializing();
        end

        % Button pushed function: Play_btn
        function Play_btnPushed(app, event)
            app.Pause = false;
            if(app.FrameCount == 0)
                app.TrackOnVideo();
            end
        end

        % Button pushed function: Pause_btn
        function Pause_btnPushed(app, event)
            %Pause video
            app.Pause=true;
        end

        % Button pushed function: AddTrackingPoint
        function AddTrackingPointButtonPushed(app, event)
            %Pause video
            app.Pause=true;
            app.AddTrackingP = true;
        end

        % Button pushed function: Plot3D_btn
        function Plot3D_btnButtonPushed(app, event)
            app.Plot3D();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.OpenFile_M = uibutton(app.UIFigure, 'push');
            app.OpenFile_M.ButtonPushedFcn = createCallbackFcn(app, @OpenFile_MPushed, true);
            app.OpenFile_M.Icon = 'folder.png';
            app.OpenFile_M.Text = '';
            app.Play_btn = uibutton(app.UIFigure, 'push');
            app.Play_btn.ButtonPushedFcn = createCallbackFcn(app, @Play_btnPushed, true);
            app.Play_btn.Icon = 'play-button.png';
            app.Play_btn.Text = 'Play';
            app.Pause_btn = uibutton(app.UIFigure, 'push');
            app.Pause_btn.ButtonPushedFcn = createCallbackFcn(app, @Pause_btnPushed, true);
            app.Pause_btn.Icon = 'pause.png';
            app.Pause_btn.Text = '';
            app.TongueTrackingSystemLabel = uilabel(app.UIFigure);
            app.TongueTrackingSystemLabel.Text = 'Tongue Tracking System';
            app.AddTrackingPoint = uibutton(app.UIFigure, 'push');
            app.AddTrackingPoint.ButtonPushedFcn = createCallbackFcn(app, @AddTrackingPointButtonPushed, true);
            app.AddTrackingPoint.Icon = 'track.png';
            app.AddTrackingPoint.Text = '';
            app.Plot3D_btn = uibutton(app.UIFigure, 'push');
            app.Plot3D_btn.ButtonPushedFcn = createCallbackFcn(app, @Plot3D_btnButtonPushed, true);
            app.Plot3D_btn.Icon = '3d-model.png';
            app.Plot3D_btn.Text = '';
            app.TrackingPointLabel = uilabel(app.UIFigure);
            app.TrackingPointLabel.Text = {'Tracking Point'; ''};
            app.FrameLabel = uilabel(app.UIFigure);
            app.FrameLabel.Text = 'Frame:';
            app.FrameCount_txt = uilabel(app.UIFigure);
            app.FrameCount_txt.Text = {'0'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TongueTrackerApp2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end